classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        axMain                    matlab.ui.control.UIAxes
        axLFP                     matlab.ui.control.UIAxes
        txtstatus                 matlab.ui.control.EditField
        RecordExperimentPanel     matlab.ui.container.Panel
        LoadStimFileButton        matlab.ui.control.Button
        Spinner                   matlab.ui.control.Spinner
        PreviewStimButton         matlab.ui.control.Button
        RunExpButton              matlab.ui.control.Button
        PlotExperimentButton      matlab.ui.control.Button
        SaveExpButton             matlab.ui.control.Button
        ClusterAnalysisPanel      matlab.ui.container.Panel
        LoadDataButton            matlab.ui.control.Button
        PlotResultsButton         matlab.ui.control.Button
        UpdateClusterButton       matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ManualClusterUpdatePanel  matlab.ui.container.Panel
        C1Label                   matlab.ui.control.Label
        txtbox1                   matlab.ui.control.EditField
        C2Label                   matlab.ui.control.Label
        txtbox2                   matlab.ui.control.EditField
        C3Label                   matlab.ui.control.Label
        txtbox3                   matlab.ui.control.EditField
        C4Label                   matlab.ui.control.Label
        txtbox4                   matlab.ui.control.EditField
        OptionsButton             matlab.ui.control.StateButton
        axWaveform                matlab.ui.control.UIAxes
        ElectrophysRecordingInterfaceLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        optionApp                   % optionApp box app
    end
    
    properties (Access = public)
        fileName = [];
        fileNameStim = [];
        fileNameFullPath = [];
        currStim = [];
        fs = 10000;
        voltageRaw = zeros(1,10000);
        voltageBS = zeros(1,10000);
        LFP = zeros(1,10000);
        
        spkNdx = [];
        SpikesAllV = [];
        
        Scale_fact = 1;
        thresh = -0.8;% in mv
        peakDist = 0.005;% in ms
        window = 6;%in ms
        
        nClust = 6;
        IdxInCluster = {};
        clusterEval = 'Silhouette';
        clusterMethod = 'Kmeans';
        
        
        DataExp = [];
        fromEphys = [];
        
    end


    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath([pwd '/Utils']))
            addpath(genpath([pwd '/Functions']))
            addpath(genpath([pwd '/Calibration']))
        end

        % Value changed function: OptionsButton
        function OptionsButtonValueChanged(app, event)
            % Disable Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.optionApp = optionsApp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.optionApp)
            delete(app)
        end

        % Button pushed function: LoadStimFileButton
        function LoadStimFileButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.mat','Select the Stim file',[pwd '/StimulusData']);
            app.fileNameStim = [PathName FileName];
            %app.fileName = FileName;
            
            S = load(app.fileNameStim);
            if isfield(S,'V')
                if iscell(S.V)
                    app.Spinner.Limits = [1 numel(S.V)];
                end
            else
                app.Spinner.Limits = [1 1];
            end
        end

        % Button pushed function: PreviewStimButton
        function PreviewStimButtonPushed(app, event)
            S = load(app.fileNameStim);
            
            StimType = app.Spinner.Value;
            app.currStim = [];
            if isfield(S,'V')
                cla(app.axMain)
                
                if isfield(S,'fs')
                    app.fs = S.fs;
                end
                if iscell(S.V)
                    app.currStim = S.V{StimType};
                else
                    app.currStim = S.V;
                end
                plot(app.axMain,(1:numel(app.currStim))./app.fs,app.currStim,'Linewidth',2);
                xlabel(app.axMain,'time (s)');ylabel(app.axMain,'stimulus (V)')
                xlim(app.axMain,[0 numel(app.currStim)./app.fs])
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.mat','Select the Stim file',[pwd '/Data']);
            
            app.fileNameFullPath = [PathName FileName];
            app.fileName = FileName;
            
            D = load(app.fileNameFullPath);
            if isfield(D, 'DataExp')
                app.currStim = D.DataExp.Stimulus;
                app.voltageRaw = D.DataExp.voltage;
                [app.voltageBS,app.LFP] = BaselineSubtraction(D.DataExp,app.fs);
            end
            
            if isfield(D, 'DataProcessed')
                app.fileName = D.DataProcessed.fileName;
                app.fileNameFullPath = D.DataProcessed.fileNameFullPath;
                app.currStim = D.DataProcessed.currStim;
                app.fs = D.DataProcessed.fs;
                app.voltageRaw = D.DataProcessed.voltageRaw;
                app.voltageBS = D.DataProcessed.voltageBS;
                app.LFP = D.DataProcessed.LFP;
                app.IdxInCluster = D.DataProcessed.IdxInCluster;%in ms
                
                app.spkNdx = D.DataProcessed.spkNdx;
                app.SpikesAllV = D.DataProcessed.SpikesAllV;
                
                app.Scale_fact = D.DataProcessed.SpkDetectParams.Scale_fact;
                app.thresh = D.DataProcessed.SpkDetectParams.thresh;% in mv
                app.peakDist = D.DataProcessed.SpkDetectParams.peakDist;% in ms
                app.window = D.DataProcessed.SpkDetectParams.window;%in ms
                
                app.nClust = D.DataProcessed.SpkClusterParams.nClust;%in ms
                app.clusterEval = D.DataProcessed.SpkClusterParams.clusterEval;%in ms
                app.clusterMethod = D.DataProcessed.SpkClusterParams.clusterMethod;%in ms
            end
            
        end

        % Button pushed function: PlotResultsButton
        function PlotResultsButtonPushed(app, event)
            [app.spkNdx,app.SpikesAllV] = SpikeDetection(app);
            if ~isempty(app.SpikesAllV)
                switch app.clusterMethod
                    % cluster based on current cluster number
                    case 'Wavelet'
                        [app.IdxInCluster] = SpikeClustering_wavelet(app,app.SpikesAllV);
                    case 'Kmeans'
                        [app.IdxInCluster] = SpikeClustering_Kmeans(app.SpikesAllV,app.nClust,'Silhouette');
                end
            end
            PlottingFunctionsGUI_new(app,app.spkNdx,app.SpikesAllV,app.currStim,app.IdxInCluster);
        end

        % Button pushed function: UpdateClusterButton
        function UpdateClusterButtonPushed(app, event)
            C{1} = get(app.txtbox1,'Value');
            C{2} = get(app.txtbox2,'Value');
            C{3} = get(app.txtbox3,'Value');
            C{4} = get(app.txtbox4,'Value');
            tmp = app.IdxInCluster;app.IdxInCluster = [];
            for i = 1:4
                temp = strsplit(C{i},',');
                temp = str2double(temp);
                try
                    app.IdxInCluster{i} = cell2mat(tmp(temp)');
                catch
                end
            end
            app.nClust = numel(app.IdxInCluster);
            PlottingFunctionsGUI_new(app,app.spkNdx,app.SpikesAllV,app.currStim,app.IdxInCluster);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            load(app.fileNameFullPath,"DataExp");
            DataProcessed.fileName = app.fileName;
            DataProcessed.fileNameFullPath = app.fileNameFullPath;
            DataProcessed.currStim = app.currStim;
            DataProcessed.fs = app.fs;
            DataProcessed.voltageRaw = app.voltageRaw;
            DataProcessed.voltageBS = app.voltageBS;
            DataProcessed.LFP = app.LFP;
            DataProcessed.IdxInCluster = app.IdxInCluster;%in ms
            
            DataProcessed.spkNdx = app.spkNdx;
            DataProcessed.SpikesAllV = app.SpikesAllV;
            
            DataProcessed.SpkDetectParams.Scale_fact = app.Scale_fact;
            DataProcessed.SpkDetectParams.thresh = app.thresh;% in mv
            DataProcessed.SpkDetectParams.peakDist = app.peakDist;% in ms
            DataProcessed.SpkDetectParams.window = app.window;%in ms
            
            DataProcessed.SpkClusterParams.nClust = app.nClust;%in ms
            DataProcessed.SpkClusterParams.clusterEval = app.clusterEval;%in ms
            DataProcessed.SpkClusterParams.clusterMethod = app.clusterMethod;%in ms
            
            save([app.fileNameFullPath(1:end-4) '_processed.mat'],"DataProcessed","DataExp","-v7.3")
        end

        % Button pushed function: RunExpButton
        function RunExpButtonPushed(app, event)
            PreviewStimButtonPushed(app, event);
            if ~isempty(app.currStim)
                disp('running')
                set(app.txtstatus, 'Value', 'Current Status: Running');
                [app.DataExp,app.fromEphys] = Optogenetics_ephys(app.currStim,app.fileNameStim,app.Spinner.Value);
            end
            pause(1);
            set(app.txtstatus, 'Value', 'Current Status: Standby');
        end

        % Button pushed function: PlotExperimentButton
        function PlotExperimentButtonPushed(app, event)
            if ~isempty(app.DataExp)
                app.fs = app.fromEphys.sampleRate;
                [app.spkNdx,app.SpikesAllV] = SpikeDetection(app);
                if ~isempty(app.SpikesAllV)
                    switch app.clusterMethod
                        % cluster based on current cluster number
                        case 'Wavelet'
                            [app.IdxInCluster] = SpikeClustering_wavelet(app,app.SpikesAllV);
                        case 'Kmeans'
                            [app.IdxInCluster] = SpikeClustering_Kmeans(app.SpikesAllV,app.nClust,'Silhouette');
                    end
                end
                PlottingFunctionsGUI_new(app,app.spkNdx,app.SpikesAllV,app.currStim,app.IdxInCluster);
                
                app.DataExp.IdxInCluster = app.IdxInCluster;
                app.DataExp.voltageBS = app.voltageBS;
                app.DataExp = orderfields(app.DataExp);
            end
        end

        % Button pushed function: SaveExpButton
        function SaveExpButtonPushed(app, event)
            if ~isempty(app.DataExp)
                %DataExp = app.DataExp;
                save([app.fromEphys.dataFile],'app.DataExp')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axMain = uiaxes(app.UIFigure);
            title(app.axMain, '')
            xlabel(app.axMain, 'time (ms)')
            ylabel(app.axMain, '')
            app.axLFP = uiaxes(app.UIFigure);
            title(app.axLFP, 'Local Field Potential')
            xlabel(app.axLFP, 'time (ms)')
            ylabel(app.axLFP, 'mV')
            app.txtstatus = uieditfield(app.UIFigure, 'text');
            app.txtstatus.Editable = 'off';
            app.txtstatus.Value = 'Current Status: Standby';
            app.RecordExperimentPanel = uipanel(app.UIFigure);
            app.RecordExperimentPanel.Title = ' Record Experiment';
            app.LoadStimFileButton = uibutton(app.RecordExperimentPanel, 'push');
            app.LoadStimFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimFileButtonPushed, true);
            app.LoadStimFileButton.Text = 'Load Stim File';
            app.Spinner = uispinner(app.RecordExperimentPanel);
            app.Spinner.Limits = [0 10];
            app.Spinner.Value = 1;
            app.PreviewStimButton = uibutton(app.RecordExperimentPanel, 'push');
            app.PreviewStimButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewStimButtonPushed, true);
            app.PreviewStimButton.Text = 'Preview Stim';
            app.RunExpButton = uibutton(app.RecordExperimentPanel, 'push');
            app.RunExpButton.ButtonPushedFcn = createCallbackFcn(app, @RunExpButtonPushed, true);
            app.RunExpButton.Text = 'Run Exp';
            app.PlotExperimentButton = uibutton(app.RecordExperimentPanel, 'push');
            app.PlotExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExperimentButtonPushed, true);
            app.PlotExperimentButton.Text = 'Plot Experiment';
            app.SaveExpButton = uibutton(app.RecordExperimentPanel, 'push');
            app.SaveExpButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExpButtonPushed, true);
            app.SaveExpButton.Text = 'Save Exp';
            app.ClusterAnalysisPanel = uipanel(app.UIFigure);
            app.ClusterAnalysisPanel.Title = 'Cluster Analysis';
            app.LoadDataButton = uibutton(app.ClusterAnalysisPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PlotResultsButton = uibutton(app.ClusterAnalysisPanel, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButtonPushed, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.UpdateClusterButton = uibutton(app.ClusterAnalysisPanel, 'push');
            app.UpdateClusterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateClusterButtonPushed, true);
            app.UpdateClusterButton.Text = 'Update Cluster';
            app.SaveButton = uibutton(app.ClusterAnalysisPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ManualClusterUpdatePanel = uipanel(app.ClusterAnalysisPanel);
            app.ManualClusterUpdatePanel.Title = 'Manual Cluster Update';
            app.C1Label = uilabel(app.ManualClusterUpdatePanel);
            app.C1Label.Text = 'C 1';
            app.txtbox1 = uieditfield(app.ManualClusterUpdatePanel, 'text');
            app.C2Label = uilabel(app.ManualClusterUpdatePanel);
            app.C2Label.Text = 'C 2';
            app.txtbox2 = uieditfield(app.ManualClusterUpdatePanel, 'text');
            app.C3Label = uilabel(app.ManualClusterUpdatePanel);
            app.C3Label.Text = 'C 3';
            app.txtbox3 = uieditfield(app.ManualClusterUpdatePanel, 'text');
            app.C4Label = uilabel(app.ManualClusterUpdatePanel);
            app.C4Label.Text = 'C 4';
            app.txtbox4 = uieditfield(app.ManualClusterUpdatePanel, 'text');
            app.OptionsButton = uibutton(app.ClusterAnalysisPanel, 'state');
            app.OptionsButton.ValueChangedFcn = createCallbackFcn(app, @OptionsButtonValueChanged, true);
            app.OptionsButton.Text = 'Options';
            app.axWaveform = uiaxes(app.UIFigure);
            title(app.axWaveform, 'Spike Waveforms')
            xlabel(app.axWaveform, 'time (ms)')
            ylabel(app.axWaveform, 'mV')
            app.ElectrophysRecordingInterfaceLabel = uilabel(app.UIFigure);
            app.ElectrophysRecordingInterfaceLabel.Text = 'Electrophys Recording Interface';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end