classdef optionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpikeDetectionPanel            matlab.ui.container.Panel
        ThresholdmVEditFieldLabel      matlab.ui.control.Label
        ThresholdmVEditField           matlab.ui.control.NumericEditField
        RunButton_Detect               matlab.ui.control.StateButton
        DetectionTypeButtonGroup       matlab.ui.container.ButtonGroup
        AutomateThreshButton           matlab.ui.control.ToggleButton
        UserSpecifyButton              matlab.ui.control.ToggleButton
        MinISImsEditField_2Label       matlab.ui.control.Label
        MinISImsEditField              matlab.ui.control.NumericEditField
        WindowmsEditField_2Label       matlab.ui.control.Label
        WindowmsEditField              matlab.ui.control.NumericEditField
        DataScalingPanel               matlab.ui.container.Panel
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        ScalingTypeButtonGroup         matlab.ui.container.ButtonGroup
        AutomateSNRButton              matlab.ui.control.ToggleButton
        UserSpecifyButton_2            matlab.ui.control.ToggleButton
        RunButton_Scaling              matlab.ui.control.StateButton
        PCClusterUIAxes                matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        ClusteringTab                  matlab.ui.container.Tab
        RunButton_Cluster              matlab.ui.control.StateButton
        EvalClusterDropDownLabel       matlab.ui.control.Label
        EvalClusterDropDown            matlab.ui.control.DropDown
        TypeDropDown                   matlab.ui.control.DropDown
        minofClustersEditField_2Label  matlab.ui.control.Label
        minofClustersEditField         matlab.ui.control.NumericEditField
        maxofClustersEditField_2Label  matlab.ui.control.Label
        maxofClustersEditField         matlab.ui.control.NumericEditField
        optClusterEditField_2Label     matlab.ui.control.Label
        optClusterEditField            matlab.ui.control.NumericEditField
        ClusterTendencyTab             matlab.ui.container.Tab
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        RunButton_Tendency             matlab.ui.control.StateButton
        ClusterableEditFieldLabel      matlab.ui.control.Label
        ClusterableEditField           matlab.ui.control.EditField
        HopkinStatEditFieldLabel       matlab.ui.control.Label
        HopkinStatEditField            matlab.ui.control.NumericEditField
        SaveParametersButton           matlab.ui.control.Button
        LoadParametersButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainApp;
            
            app.ThresholdmVEditField.Value = app.CallingApp.thresh;% in mv
            app.MinISImsEditField.Value = app.CallingApp.peakDist;% in ms
            app.WindowmsEditField.Value = app.CallingApp.window;%in ms;
            
            app.minofClustersEditField.Value = app.CallingApp.nClust;
            app.maxofClustersEditField.Value = app.CallingApp.nClust;
            app.EvalClusterDropDown.Value = app.CallingApp.clusterEval;
            app.TypeDropDown.Value = app.CallingApp.clusterMethod;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            delete(app)
            
        end

        % Value changed function: RunButton_Scaling
        function RunButton_ScalingValueChanged(app, event)
            %value = app.RunButton_Scaling.Value;
            tag = app.ScalingTypeButtonGroup.SelectedObject.Text;
            
            switch tag % Get Tag of selected object.
                case 'Automate (SNR)'
                    snr = abs(min(app.CallingApp.voltageBS))./std(app.CallingApp.voltageBS);
                    snr(isnan(snr)) = 1;
                    Scale_fact = 1./snr;
                otherwise
                    Scale_fact = app.ScaleFactorEditField.Value;
            end
            app.CallingApp.Scale_fact = Scale_fact;
            PlottingFunctionsGUI_new(app.CallingApp,[],[],app.CallingApp.currStim,[]);
            
            app.RunButton_Scaling.Value = false;
        end

        % Value changed function: RunButton_Detect
        function RunButton_DetectValueChanged(app, event)
            %value = app.RunButton_Detect.Value;
            
            tag = app.DetectionTypeButtonGroup.SelectedObject.Text;
            switch tag % Get Tag of selected object.
                case 'Automate Thresh'
                    app.CallingApp.thresh = quantile(app.CallingApp.voltageBS./app.CallingApp.Scale_fact,.05);
                otherwise
                    app.CallingApp.thresh = app.ThresholdmVEditField.Value;% in mv
            end
            app.CallingApp.peakDist = app.MinISImsEditField.Value;% in ms
            app.CallingApp.window = app.WindowmsEditField.Value;%in ms;
            
            [app.CallingApp.spkNdx,app.CallingApp.SpikesAllV] = SpikeDetection(app.CallingApp);
            clusterMethod = app.TypeDropDown.Value;
            switch clusterMethod
                case 'Wavelet'
                    % if wavelet, just cluster based on max cluster
                    [app.CallingApp.IdxInCluster] = SpikeClustering_wavelet(app.CallingApp,app.CallingApp.SpikesAllV);
                case 'Kmeans'
                    % if using k-means, choose between Silhouette,
                    % CalinskiHarabasz, and Jump criteria
                    [app.CallingApp.IdxInCluster] = SpikeClustering_Kmeans(app.CallingApp.SpikesAllV,app.CallingApp.nClust,'Silhouette');
            end
            PlottingFunctionsGUI_new(app.CallingApp,app.CallingApp.spkNdx,app.CallingApp.SpikesAllV,app.CallingApp.currStim,app.CallingApp.IdxInCluster);
            PlottingFunctionsGUI_PC(app.CallingApp,app,app.CallingApp.SpikesAllV,app.CallingApp.IdxInCluster)
            
            app.RunButton_Detect.Value = false;
        end

        % Value changed function: RunButton_Cluster
        function RunButton_ClusterValueChanged(app, event)
            %value = app.RunButton_Cluster.Value;
            % get the spikes
            [app.CallingApp.spkNdx,app.CallingApp.SpikesAllV] = SpikeDetection(app.CallingApp);
            
            % get the cluster method and evaluation type
            evalType = app.EvalClusterDropDown.Value;
            clusterMethod = app.TypeDropDown.Value;
            % update the main app's fields
            app.CallingApp.clusterEval = evalType;
            app.CallingApp.clusterMethod = clusterMethod;
            app.CallingApp.nClust = app.maxofClustersEditField.Value;
            
            clust2cons = app.minofClustersEditField.Value:app.maxofClustersEditField.Value;
            switch clusterMethod
                case 'Wavelet'
                    % if wavelet, just cluster based on max cluster
                    [app.CallingApp.IdxInCluster] = SpikeClustering_wavelet(app.CallingApp,app.CallingApp.SpikesAllV);
                case 'Kmeans'
                    % if using k-means, choose between Silhouette,
                    % CalinskiHarabasz, and Jump criteria
                    switch evalType
                        case 'Silhouette'
                            [app.CallingApp.IdxInCluster,optClust] = SpikeClustering_Kmeans(app.CallingApp.SpikesAllV,clust2cons,'Silhouette');
                        case 'CalinskiHarabasz'
                            [app.CallingApp.IdxInCluster,optClust] = SpikeClustering_Kmeans(app.CallingApp.SpikesAllV,clust2cons,'CalinskiHarabasz');
                        case 'Jump'
                            [app.CallingApp.IdxInCluster,optClust] = SpikeClustering_Kmeans(app.CallingApp.SpikesAllV,clust2cons,'Jump');
                    end
                    app.CallingApp.nClust = optClust;
            end
            app.optClusterEditField.Value = app.CallingApp.nClust;
            PlottingFunctionsGUI_new(app.CallingApp,app.CallingApp.spkNdx,app.CallingApp.SpikesAllV,app.CallingApp.currStim,app.CallingApp.IdxInCluster);
            PlottingFunctionsGUI_PC(app.CallingApp,app,app.CallingApp.SpikesAllV,app.CallingApp.IdxInCluster)
            
            app.RunButton_Cluster.Value = false;
        end

        % Value changed function: RunButton_Tendency
        function RunButton_TendencyValueChanged(app, event)
            %value = app.RunButton_Tendency.Value;
            [app.CallingApp.spkNdx,app.CallingApp.SpikesAllV] = SpikeDetection(app.CallingApp);
            
            clusterMethod = app.MethodDropDown.Value;
            
            switch clusterMethod
                case 'Hopkins'
                    % calculate hopkins stat
                    [H,~] = hopkins(app.CallingApp.SpikesAllV,[],'Norm');
                    app.HopkinStatEditField.Value = H;
                    
                    % clusterable if value is above 0.5
                    if H>0.5
                        app.ClusterableEditField.Value = 'True';
                    else
                        app.ClusterableEditField.Value = 'False';
                    end
                case 'VAT'
                    D = squareform(pdist(app.CallingApp.SpikesAllV));
                    [D_vat, ~, ~, ~, ~, ~] = Vat(D);
                    
                    cla(app.PCClusterUIAxes,'reset');
                    imagesc(app.PCClusterUIAxes,D_vat);
                    axis(app.PCClusterUIAxes,[0 size(app.CallingApp.SpikesAllV,1) 0 size(app.CallingApp.SpikesAllV,1)])
                    colormap(app.PCClusterUIAxes,gray)
                    xlabel(app.PCClusterUIAxes,['Traces']);
                    ylabel(app.PCClusterUIAxes,['Traces']);
                    title(app.PCClusterUIAxes,['VAT'])
            end
            app.RunButton_Tendency.Value = false;
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.mat','Select the Parameter file',[pwd '/Params']);
            
            D = load([PathName,FileName]);
            app.CallingApp.Scale_fact = D.Scale_fact;
            app.CallingApp.thresh = D.thresh;
            app.CallingApp.peakDist = D.peakDist;
            app.CallingApp.window = D.window;
            app.CallingApp.nClust = D.nClust;
            app.CallingApp.clusterEval = D.clusterEval;
            app.CallingApp.clusterMethod = D.clusterMethod;
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            dname = uigetdir('Select the Parameter file',[pwd '/Params']);
            
            d = datetime('today');
            d.Format = 'ddMMMyyyy';
            d = char(d);
            
            Scale_fact = app.CallingApp.Scale_fact;
            thresh = app.CallingApp.thresh;
            peakDist = app.CallingApp.peakDist;
            window = app.CallingApp.window;
            nClust = app.CallingApp.nClust;
            clusterEval = app.CallingApp.clusterEval;
            clusterMethod = app.CallingApp.clusterMethod;
            
            save([dname '/params_' d '.mat'],'Scale_fact','thresh','peakDist','window','nClust','clusterEval','clusterMethod');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SpikeDetectionPanel = uipanel(app.UIFigure);
            app.SpikeDetectionPanel.Title = 'Spike Detection';
            app.ThresholdmVEditFieldLabel = uilabel(app.SpikeDetectionPanel);
            app.ThresholdmVEditFieldLabel.Text = 'Threshold (mV)';
            app.ThresholdmVEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.ThresholdmVEditField.Value = -0.05;
            app.RunButton_Detect = uibutton(app.SpikeDetectionPanel, 'state');
            app.RunButton_Detect.ValueChangedFcn = createCallbackFcn(app, @RunButton_DetectValueChanged, true);
            app.RunButton_Detect.Text = 'Run';
            app.DetectionTypeButtonGroup = uibuttongroup(app.SpikeDetectionPanel);
            app.DetectionTypeButtonGroup.Title = 'Detection Type';
            app.AutomateThreshButton = uitogglebutton(app.DetectionTypeButtonGroup);
            app.AutomateThreshButton.Text = 'Automate Thresh';
            app.AutomateThreshButton.Value = true;
            app.UserSpecifyButton = uitogglebutton(app.DetectionTypeButtonGroup);
            app.UserSpecifyButton.Text = 'User Specify';
            app.MinISImsEditField_2Label = uilabel(app.SpikeDetectionPanel);
            app.MinISImsEditField_2Label.Text = 'Min ISI (ms)';
            app.MinISImsEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.MinISImsEditField.Limits = [0 Inf];
            app.MinISImsEditField.Value = 0.009;
            app.WindowmsEditField_2Label = uilabel(app.SpikeDetectionPanel);
            app.WindowmsEditField_2Label.Text = 'Window (ms)';
            app.WindowmsEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.WindowmsEditField.Value = 6;
            app.DataScalingPanel = uipanel(app.UIFigure);
            app.DataScalingPanel.Title = 'Data Scaling';
            app.ScaleFactorEditFieldLabel = uilabel(app.DataScalingPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.DataScalingPanel, 'numeric');
            app.ScaleFactorEditField.Value = 1;
            app.ScalingTypeButtonGroup = uibuttongroup(app.DataScalingPanel);
            app.ScalingTypeButtonGroup.Title = 'Scaling Type';
            app.AutomateSNRButton = uitogglebutton(app.ScalingTypeButtonGroup);
            app.AutomateSNRButton.Text = 'Automate (SNR)';
            app.AutomateSNRButton.Value = true;
            app.UserSpecifyButton_2 = uitogglebutton(app.ScalingTypeButtonGroup);
            app.UserSpecifyButton_2.Text = 'User Specify';
            app.RunButton_Scaling = uibutton(app.DataScalingPanel, 'state');
            app.RunButton_Scaling.ValueChangedFcn = createCallbackFcn(app, @RunButton_ScalingValueChanged, true);
            app.RunButton_Scaling.Text = 'Run';
            app.PCClusterUIAxes = uiaxes(app.UIFigure);
            title(app.PCClusterUIAxes, 'PC Space')
            xlabel(app.PCClusterUIAxes, 'PC1')
            ylabel(app.PCClusterUIAxes, 'PC2')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.RunButton_Cluster = uibutton(app.ClusteringTab, 'state');
            app.RunButton_Cluster.ValueChangedFcn = createCallbackFcn(app, @RunButton_ClusterValueChanged, true);
            app.RunButton_Cluster.Text = 'Run';
            app.EvalClusterDropDownLabel = uilabel(app.ClusteringTab);
            app.EvalClusterDropDownLabel.Text = 'Eval Cluster';
            app.EvalClusterDropDown = uidropdown(app.ClusteringTab);
            app.EvalClusterDropDown.Items = {'Silhouette', 'CalinskiHarabasz', 'Jump'};
            app.EvalClusterDropDown.Value = 'Silhouette';
            app.TypeDropDown = uidropdown(app.ClusteringTab);
            app.TypeDropDown.Items = {'Kmeans', 'Wavelet'};
            app.TypeDropDown.Value = 'Kmeans';
            app.minofClustersEditField_2Label = uilabel(app.ClusteringTab);
            app.minofClustersEditField_2Label.Text = 'min # of Clusters';
            app.minofClustersEditField = uieditfield(app.ClusteringTab, 'numeric');
            app.minofClustersEditField.Value = 4;
            app.maxofClustersEditField_2Label = uilabel(app.ClusteringTab);
            app.maxofClustersEditField_2Label.Text = 'max # of Clusters';
            app.maxofClustersEditField = uieditfield(app.ClusteringTab, 'numeric');
            app.maxofClustersEditField.Value = 4;
            app.optClusterEditField_2Label = uilabel(app.ClusteringTab);
            app.optClusterEditField_2Label.Text = 'opt # Cluster';
            app.optClusterEditField = uieditfield(app.ClusteringTab, 'numeric');
            app.optClusterEditField.Editable = 'off';
            app.ClusterTendencyTab = uitab(app.TabGroup);
            app.ClusterTendencyTab.Title = 'Cluster Tendency';
            app.MethodDropDownLabel = uilabel(app.ClusterTendencyTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.ClusterTendencyTab);
            app.MethodDropDown.Items = {'Hopkins', 'VAT'};
            app.MethodDropDown.Value = 'Hopkins';
            app.RunButton_Tendency = uibutton(app.ClusterTendencyTab, 'state');
            app.RunButton_Tendency.ValueChangedFcn = createCallbackFcn(app, @RunButton_TendencyValueChanged, true);
            app.RunButton_Tendency.Text = 'Run';
            app.ClusterableEditFieldLabel = uilabel(app.ClusterTendencyTab);
            app.ClusterableEditFieldLabel.Text = 'Clusterable?';
            app.ClusterableEditField = uieditfield(app.ClusterTendencyTab, 'text');
            app.ClusterableEditField.Editable = 'off';
            app.ClusterableEditField.Value = 'False';
            app.HopkinStatEditFieldLabel = uilabel(app.ClusterTendencyTab);
            app.HopkinStatEditFieldLabel.Text = 'Hopkin Stat';
            app.HopkinStatEditField = uieditfield(app.ClusterTendencyTab, 'numeric');
            app.HopkinStatEditField.Limits = [0 1];
            app.HopkinStatEditField.Editable = 'off';
            app.HopkinStatEditField.Value = 0.5;
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end